classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MainProcessPanel         matlab.ui.container.Panel
        UploadImageButton        matlab.ui.control.Button
        ThresholdSliderLabel     matlab.ui.control.Label
        ThresholdSlider          matlab.ui.control.Slider
        GrainSizeEditFieldLabel  matlab.ui.control.Label
        GrainSizeEditField       matlab.ui.control.NumericEditField
        Spinner                  matlab.ui.control.Spinner
        ShowImageListBoxLabel    matlab.ui.control.Label
        ShowImageListBox         matlab.ui.control.ListBox
        AreaRatioEditFieldLabel  matlab.ui.control.Label
        AreaRatioEditField       matlab.ui.control.NumericEditField
        ShowGraphListBoxLabel    matlab.ui.control.Label
        ShowGraphListBox         matlab.ui.control.ListBox
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            
            % For corn.tif, read the second image in the file 
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            A=uigetfile('*');
            imshow(A,'Parent',app.UIAxes);
            
            A=imread(A);
            A2=rgb2gray(A);
            imshow(A2,'Parent',app.UIAxes_2);
            setappdata(0,'A',A);
            setappdata(0,'A2',A2);
            th1=(0:1:255);
            i=0;
            A3=A2;
            White =rand(256,1);
             if i <= 255
                A3(A3<th1(i+1))=0;
                A3(A3>th1(i+1))=255;
                White(i+1)=nnz(A3);
                i=i+1;
                A3=A2;
             end
             %plot(app.UIAxes_3,th1,White);
             histogram(app.UIAxes_3,A2)

%             mass = app.MassEditField.Value;
%             acc = app.AccEditField.Value;
%             force = mass*acc;
%             x = [1 2 3 4 5]; 
%             y = [1 2 3 4 5];
            
%             app.ForceEditField.Value = force;
%             if force > 0 && force <= 500
%                 app.Lamp.Color = 'g';
%             elseif force > 500 && force <= 1000
%                 app.Lamp.Color = 'r';
%             else 
%                 app.Lamp.Color = 'w';
%             end
        end

        % Callback function
        function ImageClicked(app, event)
        
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            th1=app.ThresholdSlider.Value;
            app.Spinner.Value=th1;
            A = getappdata(0,'A');
            A2=rgb2gray(A);
            A2(A2<th1)=0;
            A2(A2>th1)=255;
            White=nnz(A2);
            m=size(A2);
            Area_ratio = White/( m(1)*m(2));
            app.AreaRatioEditField.Value=Area_ratio;
            imshow(A2,'Parent',app.UIAxes_2);
            histogram(app.UIAxes_3,A2)
            
            
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            th1=app.Spinner.Value;
            app.ThresholdSlider.Value=th1;
            A = getappdata(0,'A');
            A2=rgb2gray(A);
            A2(A2<th1)=0;
            A2(A2>th1)=255;
            White=nnz(A2);
            m=size(A2);
            Area_ratio = White/( m(1)*m(2));
            app.AreaRatioEditField.Value=Area_ratio;
            imshow(A2,'Parent',app.UIAxes_2);
            histogram(app.UIAxes_3,A2)
        end

        % Callback function: ShowImageListBox, ThresholdSlider
        function ShowImageListBoxValueChanged(app, event)
            value = app.ShowImageListBox.Value;
            th1=app.ThresholdSlider.Value;
            A = getappdata(0,'A');
            A3=rgb2gray(A);
            A2=rgb2gray(A);
            A2(A2<th1)=0;
            A2(A2>th1)=255;
            D=size(A2);
            
            for i=1:D(1)
                for j=1:D(2)
                    if A2(i,j) ==255
                        A2(i,j) =A3(i,j); 
                    else
                        A2(i,j) =0; 
                    end
                    j=j+1;
                end
                i=i+1;
            end 
            
            if strcmp(value,'(no colormap)')
                A2=A2;
            elseif strcmp(value,'Original Image')
                imshow(A2,'Parent',app.UIAxes_2)
            else
                colorbar(app.UIAxes_2);
                colormap(app.UIAxes_2, jet);
                imshow(A2,'Parent',app.UIAxes_2,jet)
            end
        end

        % Value changed function: ShowGraphListBox
        function ShowGraphListBoxValueChanged(app, event)
            value = app.ShowGraphListBox.Value;
            th1=app.ThresholdSlider.Value;
            A = getappdata(0,'A');
            A3=rgb2gray(A);
            
            A4=A3;
            
            for th1=1:256
                A4(A4<(th1-1))=0;
                A4(A4>(th1-1))=255;
                wh=nnz(A4);
                m=size(A4);
                AR(th1)=wh/(m(1)*m(2));
                A4=A3;
            end
            th=0:1:255;

            
            if strcmp(value,'Intensity Histogram')
                imshow(A2,'Parent',app.UIAxes_2);
                histogram(app.UIAxes_3,A2)
            else 
               plot(app.UIAxes_3,th,AR);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainProcessPanel = uipanel(app.UIFigure);
            app.MainProcessPanel.Title = 'Main Process';
            app.UploadImageButton = uibutton(app.MainProcessPanel, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = {'Upload Image '; ''};
            app.ThresholdSliderLabel = uilabel(app.MainProcessPanel);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.MainProcessPanel);
            app.ThresholdSlider.Limits = [0 255];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ShowImageListBoxValueChanged, true);
            app.GrainSizeEditFieldLabel = uilabel(app.MainProcessPanel);
            app.GrainSizeEditFieldLabel.Text = 'Grain Size';
            app.GrainSizeEditField = uieditfield(app.MainProcessPanel, 'numeric');
            app.Spinner = uispinner(app.MainProcessPanel);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.ShowImageListBoxLabel = uilabel(app.MainProcessPanel);
            app.ShowImageListBoxLabel.Text = {'Show Image'; ''};
            app.ShowImageListBox = uilistbox(app.MainProcessPanel);
            app.ShowImageListBox.Items = {'(no colormap)', 'Original Image', 'Area Image', 'Thickness Image'};
            app.ShowImageListBox.ValueChangedFcn = createCallbackFcn(app, @ShowImageListBoxValueChanged, true);
            app.ShowImageListBox.Value = '(no colormap)';
            app.AreaRatioEditFieldLabel = uilabel(app.MainProcessPanel);
            app.AreaRatioEditFieldLabel.Text = 'Area Ratio';
            app.AreaRatioEditField = uieditfield(app.MainProcessPanel, 'numeric');
            app.ShowGraphListBoxLabel = uilabel(app.MainProcessPanel);
            app.ShowGraphListBoxLabel.Text = 'Show Graph';
            app.ShowGraphListBox = uilistbox(app.MainProcessPanel);
            app.ShowGraphListBox.Items = {'Intensity Histogram', 'Area Ratio Image', ''};
            app.ShowGraphListBox.ValueChangedFcn = createCallbackFcn(app, @ShowGraphListBoxValueChanged, true);
            app.ShowGraphListBox.Value = 'Intensity Histogram';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image ')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Processed Image ')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Histogram')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end